classdef AAA_Iter5 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        FileMenu                     matlab.ui.container.Menu
        LoadMATMenu                  matlab.ui.container.Menu
        SaveMATMenu                  matlab.ui.container.Menu
        GridLayout                   matlab.ui.container.GridLayout
        LeftPanel                    matlab.ui.container.Panel
        TabGroup                     matlab.ui.container.TabGroup
        SETUPTab                     matlab.ui.container.Tab
        GridLayout6                  matlab.ui.container.GridLayout
        Panel_6                      matlab.ui.container.Panel
        GridLayout7                  matlab.ui.container.GridLayout
        TemperatureLabel             matlab.ui.control.Label
        EditField_5                  matlab.ui.control.NumericEditField
        EditField_4                  matlab.ui.control.NumericEditField
        EditField_3                  matlab.ui.control.NumericEditField
        AtmosphericPressurePaLabel   matlab.ui.control.Label
        RelativeAirHumidityLabel     matlab.ui.control.Label
        TemperaturedegCLabel         matlab.ui.control.Label
        Panel_4                      matlab.ui.container.Panel
        GridLayout11                 matlab.ui.container.GridLayout
        Panel_8                      matlab.ui.container.Panel
        GridLayout12                 matlab.ui.container.GridLayout
        EditField_9                  matlab.ui.control.NumericEditField
        EditField_8                  matlab.ui.control.NumericEditField
        EditField_7                  matlab.ui.control.NumericEditField
        EditField_6                  matlab.ui.control.NumericEditField
        x34mmLabel                   matlab.ui.control.Label
        x12mmLabel                   matlab.ui.control.Label
        d0mmLabel                    matlab.ui.control.Label
        dlmmLabel                    matlab.ui.control.Label
        GeometryLabel                matlab.ui.control.Label
        Panel_5                      matlab.ui.container.Panel
        GridLayout9                  matlab.ui.container.GridLayout
        EditField_2                  matlab.ui.control.NumericEditField
        EditField                    matlab.ui.control.NumericEditField
        DurationsecondsLabel         matlab.ui.control.Label
        NumberofMLSSequencesLabel    matlab.ui.control.Label
        SigGenDropDown               matlab.ui.control.DropDown
        SignalGeneratorLabel         matlab.ui.control.Label
        Panel_3                      matlab.ui.container.Panel
        GridLayout8                  matlab.ui.container.GridLayout
        DAQDropDown                  matlab.ui.control.DropDown
        DAQDropDownLabel             matlab.ui.control.Label
        ButtonGroup                  matlab.ui.container.ButtonGroup
        FOURButton                   matlab.ui.control.ToggleButton
        TWOButton                    matlab.ui.control.ToggleButton
        ONEButton                    matlab.ui.control.ToggleButton
        MicrophoneMethodSelectLabel  matlab.ui.control.Label
        CALIBRATIONTab               matlab.ui.container.Tab
        GridLayout13                 matlab.ui.container.GridLayout
        StopButton                   matlab.ui.control.Button
        StartButton                  matlab.ui.control.Button
        FFTPanel                     matlab.ui.container.Panel
        GridLayout12_2               matlab.ui.container.GridLayout
        UIAxes_LiveFFT               matlab.ui.control.UIAxes
        MEASUREMENTTab               matlab.ui.container.Tab
        DATAPROCESSINGTab            matlab.ui.container.Tab
        GridLayout2                  matlab.ui.container.GridLayout
        UIAxes4                      matlab.ui.control.UIAxes
        UIAxes3                      matlab.ui.control.UIAxes
        UIAxes2                      matlab.ui.control.UIAxes
        UIAxes                       matlab.ui.control.UIAxes
        RightPanel                   matlab.ui.container.Panel
        GridLayout3                  matlab.ui.container.GridLayout
        Panel_2                      matlab.ui.container.Panel
        GridLayout5                  matlab.ui.container.GridLayout
        ListBox                      matlab.ui.control.ListBox
        Panel                        matlab.ui.container.Panel
        GridLayout4                  matlab.ui.container.GridLayout
        Panel_7                      matlab.ui.container.Panel
        GridLayout10                 matlab.ui.container.GridLayout
        Button_2                     matlab.ui.control.Button
        Button_3                     matlab.ui.control.Button
        Button                       matlab.ui.control.Button
        DefaultCheckBox4             matlab.ui.control.CheckBox
        DefaultCheckBox3             matlab.ui.control.CheckBox
        DefaultCheckBox2             matlab.ui.control.CheckBox
        DefaultCheckBox1             matlab.ui.control.CheckBox
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)

        button_value = 1; %[1,2,3 for plots]
        %changed_list_box_position = 0;
        check_boxes_arr_1 = [1,1,1,1];% 4 values for the 4 checkboxes in 1st page of processing tab 
        check_boxes_arr_2 = [1,1,1,1];% 4 values for the 4 checkboxes in 2nd page of processing tab 
        check_boxes_arr_3 = [1,1,1,1];% 4 values for the 4 checkboxes in 3rd page of processing tab 
        dynamic_axes_1; % dynamic for 4 plots 
        dynamic_axes_2; % dynamic for 4 plots 
        dynamic_axes_3; % dynamic for 4 plots 
        dynamic_axes_4; % dynamic for 4 plots 
        dynamic_grid_layout; % plot layout
        filename_data_arr = {}; % for imported data TODO store measured here as well
        first_data_import = true; % 1 off to check whether you loaded anything before
        list_box_index = 1; 
        load_screen_popup;
        number_check_boxes_selected_1 = 4; %safety check on how many plots are selected per page (+used in grid row)
        number_check_boxes_selected_2 = 4; 
        number_check_boxes_selected_3 = 4; 
        number_data_loaded_in = 1; 
        previous_button_value = 0;
        previous_list_box_data = ["0","0","0","0","0","0","0","0","0","0"]; % limit of 10 imported data items (increase if needed)
        save_screen_popup;
        selected_list_item;
        %selected_list_item_array= []; 
        stored_tab_data; 

        % Measurement DAQ properties
        DAQMeasurementTypes = {'Voltage','IEPE','Audio'};    % DAQ input measurement types supported by the app
        DAQSubsystemTypes   = {'AnalogInput','AudioInput'};  % DAQ subsystem types supported by the app
        DAQVendorTypes = {'dt'};                                  % use only Data Translation DAQ
        DAQdata = struct("Timestamps", [] ,"ch1_data", [] ,"ch1_dataPa", [] );
        DAQ ;
        DevicesInfo           % Array of devices that provide analog input voltage or audio input measurements
        LivePlotLine;
        LiveTime;
        LiveFreq;
        LiveData;

    end

    properties (Access = private)
        max_number_tab_data = 10;
    end

    methods (Access = public)
    
        function plot_multiple_data(app)

            data_arr = [];

            if app.button_value == 1 
                data_arr = app.check_boxes_arr_1; 
            elseif app.button_value == 2
                data_arr = app.check_boxes_arr_2; 
            elseif app.button_value == 3
                data_arr = app.check_boxes_arr_3; 
            end
            
            if data_arr(1) == 1
                cla(app.dynamic_axes_1);
            end

            if data_arr(2) == 1
                cla(app.dynamic_axes_2);
            end
            
            if data_arr(3) == 1
                cla(app.dynamic_axes_3);
            end

            if data_arr(4) == 1
                cla(app.dynamic_axes_4);
            end

            % if app.button_value ~= 3
            % 
            %     if data_arr(3) == 1
            %         cla(app.dynamic_axes_3);
            %     end
            % 
            %     if data_arr(4) == 1
            %         cla(app.dynamic_axes_4);
            %     end
            % end

            for i = 1:max(size(app.ListBox.Items)) 
           
                %need to compare each one of the list box items value with
                %the chosen items and get their item position. 

                for j = 1:max(size(app.previous_list_box_data)) 
                    if app.ListBox.Items(i) == app.previous_list_box_data(j)
                        display_name = cell2mat(app.filename_data_arr(i));

                        if app.button_value == 1

                            if data_arr(1) == 1
                                hold(app.dynamic_axes_1, "on"); 
                                app.stored_tab_data(i).plot_absorption_coeff_figure(app.dynamic_axes_1, display_name);
                            end

                            if data_arr(2) == 1
                                hold(app.dynamic_axes_2, "on"); 
                                app.stored_tab_data(i).plot_reflection_factor_figure(app.dynamic_axes_2, display_name);
                            end

                            if data_arr(3) == 1
                                hold(app.dynamic_axes_3, "on"); 
                                app.stored_tab_data(i).plot_complex_surface_impedance_figure(app.dynamic_axes_3, display_name);
                            end

                            if data_arr(4) == 1
                                hold(app.dynamic_axes_4, "on"); 
                                app.stored_tab_data(i).plot_complex_surface_impedance_phase_figure(app.dynamic_axes_4, display_name);
                            end

                        elseif app.button_value == 2

                            if data_arr(1) == 1
                                hold(app.dynamic_axes_1, "on"); 
                                app.stored_tab_data(i).plot_complex_wavenumber_figure(app.dynamic_axes_1, display_name);
                            end

                            if data_arr(2) == 1
                                hold(app.dynamic_axes_2, "on"); 
                                app.stored_tab_data(i).plot_complex_wavenumber_figure_phase(app.dynamic_axes_2, display_name);
                            end

                            if data_arr(3) == 1
                                hold(app.dynamic_axes_3, "on"); 
                                app.stored_tab_data(i).plot_M12_Pressure_data_ch1(app.dynamic_axes_3, display_name);
                            end

                            if data_arr(4) == 1
                                hold(app.dynamic_axes_4, "on"); 
                                app.stored_tab_data(i).plot_M12_Pressure_data_ch2(app.dynamic_axes_4, display_name);
                            end

                        elseif app.button_value == 3

                            if data_arr(1) == 1
                                hold(app.dynamic_axes_1, "on"); 
                                app.stored_tab_data(i).plot_M21_Pressure_data_ch1(app.dynamic_axes_1, display_name);
                            end

                            if data_arr(2) == 1
                                hold(app.dynamic_axes_2, "on"); 
                                app.stored_tab_data(i).plot_M21_Pressure_data_ch2(app.dynamic_axes_2, display_name);
                            end

                            if data_arr(3) == 1
                                hold(app.dynamic_axes_3, "on"); 
                                app.stored_tab_data(i).plot_absorption_coeff_figure(app.dynamic_axes_3, display_name);
                            end

                            if data_arr(4) == 1
                                hold(app.dynamic_axes_4, "on"); 
                                app.stored_tab_data(i).plot_reflection_factor_figure(app.dynamic_axes_4, display_name);
                            end

                        end
                    end
                end
            end
        end
    end


    methods (Access = private)

        function tab_data_class_initializer(app)
            for i = 1:app.max_number_tab_data
                tab_data_class_storage(i) = AAA_Iter5_class_variables; 
            end
            app.stored_tab_data = tab_data_class_storage;  
        end
        
        function change_measurement_visibility(app, bool)

            app.EditField.Visible = bool; 
            app.EditField_2.Visible = bool; 
            app.DurationsecondsLabel.Visible = bool; 
            app.NumberofMLSSequencesLabel.Visible = bool; 
        end

        function open_load_screen_popup(app)
            app.load_screen_popup = AAA_Iter5_Load(app); 
        end

        function open_save_screen_popup(app)
            app.save_screen_popup = AAA_Iter5_Save(app);
        end

        function reset_button_colours(app)

            app.Button.BackgroundColor = [1,1,1];
            app.Button.FontColor = [0,0,0];

            app.Button_2.BackgroundColor = [1,1,1];
            app.Button_2.FontColor = [0,0,0];

            app.Button_3.BackgroundColor = [1,1,1];
            app.Button_3.FontColor = [0,0,0];
        end


        function check_box_value_changed(app, value, check_box_number)
            
            if app.button_value == 1
                if value == 0 
                    app.number_check_boxes_selected_1 = app.number_check_boxes_selected_1 - 1; 
                elseif value == 1
                    app.number_check_boxes_selected_1 = app.number_check_boxes_selected_1 + 1; 
                end
                app.check_boxes_arr_1(check_box_number) = value;
            elseif app.button_value == 2
                if value == 0 
                    app.number_check_boxes_selected_2 = app.number_check_boxes_selected_2 - 1; 
                elseif value == 1
                    app.number_check_boxes_selected_2 = app.number_check_boxes_selected_2 + 1; 
                end
                app.check_boxes_arr_2(check_box_number) = value;
            elseif app.button_value == 3
                if value == 0 
                    app.number_check_boxes_selected_3 = app.number_check_boxes_selected_3 - 1; 
                elseif value == 1
                    app.number_check_boxes_selected_3 = app.number_check_boxes_selected_3 + 1; 
                end
                app.check_boxes_arr_3(check_box_number) = value;
            end
% 
%             disp(app.check_boxes_arr_1);
%             disp(app.check_boxes_arr_2);
%             disp(app.check_boxes_arr_3);

        end


        function resize_grid_layout(app)

            delete(app.dynamic_grid_layout);

            new_grid_layout = uigridlayout(app.DATAPROCESSINGTab); 

            resize_row_height(app, new_grid_layout);

            app.dynamic_grid_layout = new_grid_layout;

            if app.button_value == 1

                if app.check_boxes_arr_1(1) == 1
                    app.dynamic_axes_1 = uiaxes(app.dynamic_grid_layout); 
                end

                if app.check_boxes_arr_1(2) == 1
                    app.dynamic_axes_2 = uiaxes(app.dynamic_grid_layout); 
                end

                if app.check_boxes_arr_1(3) == 1
                    app.dynamic_axes_3 = uiaxes(app.dynamic_grid_layout); 
                end

                if app.check_boxes_arr_1(4) == 1
                    app.dynamic_axes_4 = uiaxes(app.dynamic_grid_layout); 
                end
              
            elseif app.button_value == 2

                if app.check_boxes_arr_2(1) == 1
                    app.dynamic_axes_1 = uiaxes(app.dynamic_grid_layout); 
                end

                if app.check_boxes_arr_2(2) == 1
                    app.dynamic_axes_2 = uiaxes(app.dynamic_grid_layout); 
                end

                if app.check_boxes_arr_2(3) == 1
                    app.dynamic_axes_3 = uiaxes(app.dynamic_grid_layout); 
                end
                
                if app.check_boxes_arr_2(4) == 1
                    app.dynamic_axes_4 = uiaxes(app.dynamic_grid_layout); 
                end

            elseif app.button_value == 3

                if app.check_boxes_arr_3(1) == 1
                    app.dynamic_axes_1 = uiaxes(app.dynamic_grid_layout); 
                end

                if app.check_boxes_arr_3(2) == 1
                    app.dynamic_axes_2 = uiaxes(app.dynamic_grid_layout); 
                end

                if app.check_boxes_arr_3(3) == 1
                    app.dynamic_axes_3 = uiaxes(app.dynamic_grid_layout); 
                end
                
                if app.check_boxes_arr_3(4) == 1
                    app.dynamic_axes_4 = uiaxes(app.dynamic_grid_layout); 
                end

            end
            
        end


         function resize_row_height(app, grid_layout)

            grid_layout.ColumnWidth = {'1x'};

            if app.button_value == 1
                number_open_figures = app.number_check_boxes_selected_1;
            elseif app.button_value == 2
                number_open_figures = app.number_check_boxes_selected_2;
            elseif app.button_value == 3
                number_open_figures = app.number_check_boxes_selected_3; 
            end

            if number_open_figures == 4
                grid_layout.RowHeight = {'1x', '1x', '1x', '1x'};
            elseif number_open_figures == 3
                grid_layout.RowHeight = {'1x', '1x', '1x'};
            elseif number_open_figures == 2
                grid_layout.RowHeight = {'1x', '1x'};
            elseif number_open_figures == 1
                grid_layout.RowHeight = {'1x'};
            end
         end

                function scansAvailable_Callback(app, src, ~)
        %scansAvailable_Callback Executes on DAQ object ScansAvailable event
        %  This callback function gets executed periodically as more data is acquired.
        %  For a smooth live plot update, it stores the latest N seconds
        %  (specified time window) of acquired data and relative timestamps in FIFO
        %  buffers. A live plot is updated with the data in the FIFO buffer.
        %  If data logging option is selected in the UI, it also writes data to a
        %  binary file.
            
            if ~isvalid(app)
                return
            end
            
            [data,timestamps,triggertime] = read(src, src.ScansAvailableFcnCount, 'OutputFormat','Matrix');

            %app.LiveTime = timestamps;
            %app.LiveData = data;
            Fs = app.DAQ.Rate;
            mic1_sensitivity = 13.258056042941E-3 %V/Pa
            data_ch1Pa = data./mic1_sensitivity;
            
            lengthOfData = length(data_ch1Pa);
            % next closest power of 2 to the length
            nextPowerOfTwo = 2 ^ nextpow2(lengthOfData);
            
            plotScaleFactor = 1;
            % plot is symmetric about n/2
            plotRange = nextPowerOfTwo / 2; 
            plotRange = floor(plotRange / plotScaleFactor);
            
            yDFT = fft(data_ch1Pa, nextPowerOfTwo); 
            
            h = yDFT(1:plotRange);
            abs_h = abs(h);
            
            % Frequency range
            freqRange = (0:nextPowerOfTwo-1) * (Fs / nextPowerOfTwo);
            % Only plot up to n/2 (as other half is the mirror image)
            gfreq = freqRange(1:plotRange); 
            
            app.LiveFreq = gfreq;
            app.LiveData = 20*log10(abs_h/2e-5);

            % Save data to UserData of figure
            %figureHandle.UserData = struct("Timestamps", t , "ch1_data", data_ch1, "ch1_dataPa", data_ch1Pa);
  
            % Update plot data
            set(app.LivePlotLine, 'XData', app.LiveFreq, 'YData', app.LiveData);
           
            xlim(app.UIAxes_LiveFFT, [10, 20000]);

            % if numel(app.TimestampsFIFOBuffer) > 1
            %     xlim(app.LiveAxes, [app.TimestampsFIFOBuffer(1), app.TimestampsFIFOBuffer(end)])
            % end
       end

      
       function deviceinfo = daqListSupportedDevices(app, subsystemTypes, measurementTypes, vendorTypes)
        %daqListSupportedDevices Get connected devices that support the specified subsystem and measurement types      
            
            % Detect all connected devices
            devices = daqlist;
            deviceinfo = devices.DeviceInfo;
            
            % Keep a subset of devices which have the specified subystem and measurement types

            deviceinfo = daqFilterDevicesBySubsystem(app, deviceinfo, subsystemTypes);
            deviceinfo = daqFilterDevicesByMeasurement(app, deviceinfo, measurementTypes);
            deviceinfo = daqFilterDevicesByVendor(app, deviceinfo, vendorTypes);
            
        end
                
        function filteredDevices = daqFilterDevicesBySubsystem(~, devices, subsystemTypes)
        %daqFilterDevicesBySubsystem Filter DAQ device array by subsystem type
        %  devices is a DAQ device info array
        %  subsystemTypes is a cell array of DAQ subsystem types, for example {'AnalogInput, 'AnalogOutput'}
        %  filteredDevices is the filtered DAQ device info array
            
            % Logical array indicating if device has any of the subsystem types provided
            hasSubsystemArray = false(numel(devices), 1);
            
            % Go through each device and see if it has any of the subsystem types provided
            for ii = 1:numel(devices)
                hasSubsystem = false;
                for jj = 1:numel(subsystemTypes)
                    hasSubsystem = hasSubsystem || ...
                        any(strcmp({devices(ii).Subsystems.SubsystemType}, subsystemTypes{jj}));
                end
                hasSubsystemArray(ii) = hasSubsystem;
            end
            filteredDevices = devices(hasSubsystemArray);
        end
        
        function filteredDevices = daqFilterDevicesByMeasurement(~, devices, measurementTypes)
        %daqFilterDevicesByMeasurement Filter DAQ device array by measurement type
        %  devices is a DAQ device info array
        %  measurementTypes is a cell array of measurement types, for example {'Voltage, 'Current'}
        %  filteredDevices is the filtered DAQ device info array
            
            % Logical array indicating if device has any of the measurement types provided
            hasMeasurementArray = false(numel(devices), 1);
            
            % Go through each device and subsystem and see if it has any of the measurement types provided
            for ii = 1:numel(devices)
                % Get array of available subsystems for the current device
                subsystems = [devices(ii).Subsystems];
                hasMeasurement = false;
                for jj = 1:numel(subsystems)
                    % Get cell array of available measurement types for the current subsystem
                    measurements = subsystems(jj).MeasurementTypesAvailable;
                    for kk = 1:numel(measurementTypes)
                        hasMeasurement = hasMeasurement || ...
                            any(strcmp(measurements, measurementTypes{kk}));
                    end
                end
                hasMeasurementArray(ii) = hasMeasurement;
            end
            filteredDevices = devices(hasMeasurementArray);
        end

        function filteredDevices = daqFilterDevicesByVendor(~, devices, vendorTypes)
        %daqFilterDevicesBySubsystem Filter DAQ device array by subsystem type
        %  devices is a DAQ device info array
        %  subsystemTypes is a cell array of DAQ subsystem types, for example {'AnalogInput, 'AnalogOutput'}
        %  filteredDevices is the filtered DAQ device info array
            
            % Logical array indicating if device has any of the Vendor types provided
            hasVendorArray = false(numel(devices), 1);
            
            % Go through each device and see if it has any of the subsystem types provided
            for ii = 1:numel(devices)
                hasVendor = false;
                for jj = 1:numel(vendorTypes)
                    hasVendor= hasVendor|| ...
                        any(strcmp({devices(ii).Vendor.ID}, vendorTypes{jj}));
                end
                hasVendorArray(ii) = hasVendor;
            end
            filteredDevices = devices(hasVendorArray);
        end

        function updateChannelMeasurementComponents(app)

            % Get selected DAQ device index (to be used with DaqDevicesInfo list)
            deviceIndex = app.DAQDropDown.Value - 1;
            deviceID = app.DevicesInfo(deviceIndex).ID;
            vendor = app.DevicesInfo(deviceIndex).Vendor.ID;
                        
            % Get DAQ subsystem information (analog input or audio input)
            % Analog input or analog output subsystems are the first subsystem of the device
            subsystem = app.DevicesInfo(deviceIndex).Subsystems(1);
            
            % Delete existing data acquisition object
            delete(app.DAQ);
            app.DAQ = [];
            
            % Create a new data acquisition object
            s = daq(vendor);
            
            % Add INPUT Channel (Hardcoded)
            
            ch1 = addinput(s,'DT9857E-08(00)','0','IEPE');

            % Set acquisition configuration for each channel
            ch1.ExcitationCurrent = .004;
            ch1.TerminalConfig = 'SingleEnded';
            ch1.Range = [-1 1];
            % Set acquisition rate, in scans/second
            s.Rate = 48000;
           

            % Configure DAQ ScansAvailableFcn callback function
            s.ScansAvailableFcn = @(src,event) scansAvailable_Callback(app, src, event);
  
            % Store data acquisition object handle in DAQ app property
            app.DAQ = s;
 
        end

        
        function closeApp_Callback(app, ~, event, isAcquiring)
        %closeApp_Callback Clean-up after "Close Confirm" dialog window
        %  "Close Confirm" dialog window is called from CloseRequestFcn
        %  of the app UIFigure.
        %   event is the event data of the UIFigure CloseRequestFcn callback.
        %   isAcquiring is a logical flag (true/false) corresponding to DAQ
        %   running state.            
            
            %   Before closing app if acquisition is currently on (isAcquiring=true) clean-up 
            %   data acquisition object and close file if logging.
            switch event.SelectedOption
                case 'OK'
                    if isAcquiring
                        % Acquisition is currently on
                        stop(app.DAQ)
                        delete(app.DAQ)
                        %if app.LogRequested
                        %    fclose(app.TempFile);
                        %end
                    else
                        % Acquisition is stopped
                    end

                    delete(app)
                case 'Cancel'
                    % Continue
            end
            
        end


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            tab_data_class_initializer(app); 
            app.dynamic_grid_layout = app.GridLayout2;           

            % DAQ Setup            
            
                % Create a line plot and store its handle in LivePlot app property
                % This is used for updating the live plot from scansAvailable_Callback function
                app.LivePlotLine = plot(app.UIAxes_LiveFFT, NaN, NaN);
                
                % Get connected devices that have the supported subsystem and measurement types
                devices = daqListSupportedDevices(app, app.DAQSubsystemTypes, app.DAQMeasurementTypes, app.DAQVendorTypes);
                
                % Store DAQ device information (filtered list) into DevicesInfo app property
                % This is used by other functions in the app
                app.DevicesInfo = devices;
               
                % Populate the device drop down list with cell array of composite device names (ID + model)
                % First element is "Select a device"
                deviceDescriptions = cellstr(string({devices.ID}') + " [" + string({devices.Model}') + "]");
                app.DAQDropDown.Items = ['Select a device'; deviceDescriptions];
                
                % Assign dropdown ItemsData to correspond to device index + 1
                % (first item is not a device)
                app.DAQDropDown.ItemsData = 1:numel(devices)+1;

        end

        % Value changed function: SigGenDropDown
        function SigGenDropDownValueChanged(app, event)
            value = app.SigGenDropDown.Value;
            
            if value == "Select Method:"
                change_measurement_visibility(app, false);

            elseif value == "Sine Sweep"
                change_measurement_visibility(app, true);
                app.DurationsecondsLabel.Text = "Sweep Silence";
                app.NumberofMLSSequencesLabel.Text = "Sweep Duration";
                app.EditField.Value = 9.8;
                app.EditField_2.Value = 0.1;

            elseif value == "MLS Signal"
                change_measurement_visibility(app, true);
                app.DurationsecondsLabel.Text = "Duration (seconds)";
                app.NumberofMLSSequencesLabel.Text = "Number of MLS Sequences";
                app.EditField.Value = 5;
                app.EditField_2.Value = 3;

            end
        end

        % Menu selected function: LoadMATMenu
        function LoadMATMenuSelected(app, event)
            open_load_screen_popup(app);

            if app.first_data_import == true 

                delete(app.UIAxes); 
                delete(app.UIAxes2);
                delete(app.UIAxes3);
                delete(app.UIAxes4);

                app.first_data_import = false; 

                app.dynamic_axes_1 = uiaxes(app.dynamic_grid_layout);
                app.dynamic_axes_2 = uiaxes(app.dynamic_grid_layout);
                app.dynamic_axes_3 = uiaxes(app.dynamic_grid_layout);
                app.dynamic_axes_4 = uiaxes(app.dynamic_grid_layout);

                app.DefaultCheckBox1.Text = "Absorption Coefficient";
                app.DefaultCheckBox2.Text = "Reflection Factor";
                app.DefaultCheckBox3.Text = "Complex Surface Imp.";
                app.DefaultCheckBox4.Text = "Complex Surface Imp. Phase";
            end
        end

        % Value changed function: ListBox
        function ListBoxValueChanged(app, event)
            value = app.ListBox.Value;
                % Review the loop
            for i = 1:max(size(value))
                data = value(i);           
                if app.previous_list_box_data(i) ~= "0" 
                    previous_data = app.previous_list_box_data(i);
                    data = num2str(cell2mat(data));
                    data = string(data);
                    if  previous_data == data
                        disp("Data has not changed in position");
                        disp(i);
                    else 
                        disp("Data has changed in position");
                        disp(i);
                    end
                end
                app.previous_list_box_data(i) = value(i); 
            end

            for i = (max(size(value))+1):10
                app.previous_list_box_data(i) = "0";
            end

            plot_multiple_data(app);
        end

        % Clicked callback: ListBox
        function ListBoxClicked(app, event)
            item = event.InteractionInformation.Item;
            app.selected_list_item = item;             
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            app.button_value = 1;

            delete(app.dynamic_axes_1);
            delete(app.dynamic_axes_2);
            delete(app.dynamic_axes_3);
            delete(app.dynamic_axes_4);

            resize_row_height(app, app.dynamic_grid_layout);
            % Memory of whaich checkboxx plots were selected in tabs
            if app.check_boxes_arr_1(1) == 1
                app.dynamic_axes_1 = uiaxes(app.dynamic_grid_layout); 
            end
            if app.check_boxes_arr_1(2) == 1
                app.dynamic_axes_2 = uiaxes(app.dynamic_grid_layout); 
            end
            if app.check_boxes_arr_1(3) == 1
                app.dynamic_axes_3 = uiaxes(app.dynamic_grid_layout); 
            end
            if app.check_boxes_arr_1(4) == 1
                app.dynamic_axes_4 = uiaxes(app.dynamic_grid_layout); 
            end
   
            reset_button_colours(app);         
            app.Button.BackgroundColor = [0,0,0];
            app.Button.FontColor = [1,1,1];

            % if app.previous_button_value == 3
            %     app.DefaultCheckBox3.Visible = true; 
            %     app.DefaultCheckBox4.Visible = true; 
            % end

            app.DefaultCheckBox1.Value = app.check_boxes_arr_1(1);
            app.DefaultCheckBox2.Value = app.check_boxes_arr_1(2);
            app.DefaultCheckBox3.Value = app.check_boxes_arr_1(3);
            app.DefaultCheckBox4.Value = app.check_boxes_arr_1(4);
            app.DefaultCheckBox3.Visible = true;
            app.DefaultCheckBox4.Visible = true;

            plot_multiple_data(app);
            app.previous_button_value = 1; 

            app.DefaultCheckBox1.Text = "Absorption Coefficient";
            app.DefaultCheckBox2.Text = "Reflection Factor";
            app.DefaultCheckBox3.Text = "Complex Surface Imp.";
            app.DefaultCheckBox4.Text = "Complex Surface Imp. Phase";

            
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            app.button_value = 2;
            delete(app.dynamic_axes_1);
            delete(app.dynamic_axes_2);
            delete(app.dynamic_axes_3);
            delete(app.dynamic_axes_4);

            resize_row_height(app, app.dynamic_grid_layout);
            
            if app.check_boxes_arr_2(1) == 1
                app.dynamic_axes_1 = uiaxes(app.dynamic_grid_layout); 
            end
            if app.check_boxes_arr_2(2) == 1
                app.dynamic_axes_2 = uiaxes(app.dynamic_grid_layout); 
            end
            if app.check_boxes_arr_2(3) == 1
                app.dynamic_axes_3 = uiaxes(app.dynamic_grid_layout); 
            end
            if app.check_boxes_arr_2(4) == 1
                app.dynamic_axes_4 = uiaxes(app.dynamic_grid_layout); 
            end
  
            reset_button_colours(app);
            app.Button_2.BackgroundColor = [0,0,0];
            app.Button_2.FontColor = [1,1,1];

            if app.previous_button_value == 3
                
                app.DefaultCheckBox3.Visible = true; 
                app.DefaultCheckBox4.Visible = true; 
               
            end

            app.DefaultCheckBox1.Value = app.check_boxes_arr_2(1);
            app.DefaultCheckBox2.Value = app.check_boxes_arr_2(2);
            app.DefaultCheckBox3.Value = app.check_boxes_arr_2(3);
            app.DefaultCheckBox4.Value = app.check_boxes_arr_2(4);

            app.DefaultCheckBox3.Visible = true;
            app.DefaultCheckBox4.Visible = true;


            plot_multiple_data(app);

            app.previous_button_value = 2; 

            app.DefaultCheckBox1.Text = "Complex Wavenumber";
            app.DefaultCheckBox2.Text = "Complex Wavenumber Phase";
            app.DefaultCheckBox3.Text = "M12 Pressure Data Ch1";
            app.DefaultCheckBox4.Text = "M12 Pressure Data Ch2";

            
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            app.button_value = 3;
            delete(app.dynamic_axes_1);
            delete(app.dynamic_axes_2);
            delete(app.dynamic_axes_3);
            delete(app.dynamic_axes_4);

            resize_row_height(app, app.dynamic_grid_layout);
            
            if app.check_boxes_arr_3(1) == 1
                app.dynamic_axes_1 = uiaxes(app.dynamic_grid_layout); 
            end
            if app.check_boxes_arr_3(2) == 1
                app.dynamic_axes_2 = uiaxes(app.dynamic_grid_layout); 
            end
            
            if app.check_boxes_arr_3(3) == 1
                app.dynamic_axes_3 = uiaxes(app.dynamic_grid_layout); 
            end
            if app.check_boxes_arr_3(4) == 1
                app.dynamic_axes_4 = uiaxes(app.dynamic_grid_layout); 
            end
            
            
            reset_button_colours(app);
           
            app.Button_3.BackgroundColor = [0,0,0];
            app.Button_3.FontColor = [1,1,1];

            app.DefaultCheckBox1.Value = app.check_boxes_arr_3(1);
            app.DefaultCheckBox2.Value = app.check_boxes_arr_3(2);
            app.DefaultCheckBox3.Value = app.check_boxes_arr_3(3);
            app.DefaultCheckBox4.Value = app.check_boxes_arr_3(4);
            % 
            % app.DefaultCheckBox3.Visible = false;
            % app.DefaultCheckBox4.Visible = false;
            

            plot_multiple_data(app);
            
            app.previous_button_value = 3; 

            app.DefaultCheckBox1.Text = "M21 Pressure Data Ch1";
            app.DefaultCheckBox2.Text = "M21 Pressure Data Ch2";
            
            app.DefaultCheckBox3.Text = "M12 IR Ch1 ";
            app.DefaultCheckBox4.Text = "M21 IR Ch1";

        end

        % Value changed function: DefaultCheckBox1
        function DefaultCheckBox1ValueChanged(app, event)
            value = app.DefaultCheckBox1.Value;
            check_box_value_changed(app, value, 1);

            resize_grid_layout(app);

            plot_multiple_data(app);
            
        end

        % Value changed function: DefaultCheckBox2
        function DefaultCheckBox2ValueChanged(app, event)
            value = app.DefaultCheckBox2.Value;
            check_box_value_changed(app, value, 2);
                        
            resize_grid_layout(app);

            plot_multiple_data(app);

        end

        % Value changed function: DefaultCheckBox3
        function DefaultCheckBox3ValueChanged(app, event)
            value = app.DefaultCheckBox3.Value;
            check_box_value_changed(app, value, 3);

            resize_grid_layout(app);

            plot_multiple_data(app);
            
        end

        % Value changed function: DefaultCheckBox4
        function DefaultCheckBox4ValueChanged(app, event)
            value = app.DefaultCheckBox4.Value;
            check_box_value_changed(app, value, 4);

            resize_grid_layout(app);

            plot_multiple_data(app);
        end

        % Menu selected function: SaveMATMenu
        function SaveMATMenuSelected(app, event)
            open_save_screen_popup(app)
        end

        % Value changed function: DAQDropDown
        function DAQDropDownValueChanged(app, event)
            value = app.DAQDropDown.Value;
            
            if ~isempty(value)
                % Device index is offset by 1 because first element in device dropdown
                % is "Select a device" (not a device).
                deviceIndex = value-1 ;
                
                                
                % Delete data acquisition object, as a new one will be created for the newly selected device
                delete(app.DAQ);
                app.DAQ = [];
                
                if deviceIndex > 0
                    % If a device is selected
                    
   
                    % Update channel and channel property options
                    updateChannelMeasurementComponents(app)

                else
                    % If no device is selected

                    % Delete existing data acquisition object
                    delete(app.DAQ);
                    app.DAQ = [];
                    
                    app.DAQDropDown.Items{1} = 'Select a device';

                end
            end
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            app.LiveTime = [];
            app.LiveData = [];
            
            try
                start(app.DAQ,"continuous");
            catch exception
                % In case of error show it and revert the change
                uialert(app.UIFigure, exception.message, 'Start error');   

            end

        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
              stop(app.DAQ);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            
            isAcquiring = ~isempty(app.DAQ) && app.DAQ.Running;
            if isAcquiring
                question = 'Abort acquisition and close app?';
                
            else
                % Acquisition is stopped
                question = 'Close app?';
            end
            
            uiconfirm(app.UIFigure,question,'Confirm Close',...
                'CloseFcn',@(src,event) closeApp_Callback(app,src,event,isAcquiring));
            

        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {767, 767};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {920, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.LoadMATMenu = uimenu(app.FileMenu);
            app.LoadMATMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadMATMenuSelected, true);
            app.LoadMATMenu.Text = 'Load .MAT';
            app.SaveMATMenu = uimenu(app.FileMenu);
            app.SaveMATMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveMATMenuSelected, true);
            app.SaveMATMenu.Text = 'Save .MAT';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.LeftPanel);
            app.SETUPTab = uitab(app.TabGroup);
            app.SETUPTab.Title = 'SETUP';
            app.GridLayout6 = uigridlayout(app.SETUPTab);
            app.Panel_3 = uipanel(app.GridLayout6);
            app.GridLayout8 = uigridlayout(app.Panel_3);
            app.MicrophoneMethodSelectLabel = uilabel(app.GridLayout8);
            app.MicrophoneMethodSelectLabel.Text = 'Microphone Method Select';
            app.ButtonGroup = uibuttongroup(app.GridLayout8);
            app.ONEButton = uitogglebutton(app.ButtonGroup);
            app.ONEButton.Text = 'ONE';
            app.ONEButton.Value = true;
            app.TWOButton = uitogglebutton(app.ButtonGroup);
            app.TWOButton.Text = 'TWO';
            app.FOURButton = uitogglebutton(app.ButtonGroup);
            app.FOURButton.Text = 'FOUR';
            app.DAQDropDownLabel = uilabel(app.GridLayout8);
            app.DAQDropDownLabel.Text = 'DAQ';
            app.DAQDropDown = uidropdown(app.GridLayout8);
            app.DAQDropDown.Items = {'Detecting Device'};
            app.DAQDropDown.ValueChangedFcn = createCallbackFcn(app, @DAQDropDownValueChanged, true);
            app.DAQDropDown.Value = 'Detecting Device';
            app.Panel_5 = uipanel(app.GridLayout6);
            app.GridLayout9 = uigridlayout(app.Panel_5);
            app.SignalGeneratorLabel = uilabel(app.GridLayout9);
            app.SignalGeneratorLabel.Text = 'Signal Generator';
            app.SigGenDropDown = uidropdown(app.GridLayout9);
            app.SigGenDropDown.Items = {'Select Method:', 'Sine Sweep', 'MLS Signal'};
            app.SigGenDropDown.ValueChangedFcn = createCallbackFcn(app, @SigGenDropDownValueChanged, true);
            app.SigGenDropDown.Value = 'Select Method:';
            app.NumberofMLSSequencesLabel = uilabel(app.GridLayout9);
            app.NumberofMLSSequencesLabel.Visible = 'off';
            app.NumberofMLSSequencesLabel.Text = 'Number of MLS Sequences';
            app.DurationsecondsLabel = uilabel(app.GridLayout9);
            app.DurationsecondsLabel.Visible = 'off';
            app.DurationsecondsLabel.Text = 'Duration (seconds)';
            app.EditField = uieditfield(app.GridLayout9, 'numeric');
            app.EditField.Visible = 'off';
            app.EditField_2 = uieditfield(app.GridLayout9, 'numeric');
            app.EditField_2.Visible = 'off';
            app.Panel_4 = uipanel(app.GridLayout6);
            app.GridLayout11 = uigridlayout(app.Panel_4);
            app.Panel_8 = uipanel(app.GridLayout11);
            app.GridLayout12 = uigridlayout(app.Panel_8);
            app.GeometryLabel = uilabel(app.GridLayout12);
            app.GeometryLabel.Text = 'Geometry';
            app.dlmmLabel = uilabel(app.GridLayout12);
            app.dlmmLabel.Text = 'dl (mm)';
            app.d0mmLabel = uilabel(app.GridLayout12);
            app.d0mmLabel.Text = 'd0 (mm)';
            app.x12mmLabel = uilabel(app.GridLayout12);
            app.x12mmLabel.Text = 'x12 (mm)';
            app.x34mmLabel = uilabel(app.GridLayout12);
            app.x34mmLabel.Text = 'x34 (mm)';
            app.EditField_6 = uieditfield(app.GridLayout12, 'numeric');
            app.EditField_6.Value = 40;
            app.EditField_7 = uieditfield(app.GridLayout12, 'numeric');
            app.EditField_7.Value = 40;
            app.EditField_8 = uieditfield(app.GridLayout12, 'numeric');
            app.EditField_8.Value = 175;
            app.EditField_9 = uieditfield(app.GridLayout12, 'numeric');
            app.EditField_9.Value = 175;
            app.Panel_6 = uipanel(app.GridLayout6);
            app.GridLayout7 = uigridlayout(app.Panel_6);
            app.TemperaturedegCLabel = uilabel(app.GridLayout7);
            app.TemperaturedegCLabel.Text = 'Temperature [degC]';
            app.RelativeAirHumidityLabel = uilabel(app.GridLayout7);
            app.RelativeAirHumidityLabel.Text = 'Relative Air Humidity [%]';
            app.AtmosphericPressurePaLabel = uilabel(app.GridLayout7);
            app.AtmosphericPressurePaLabel.Text = 'Atmospheric Pressure [Pa]';
            app.EditField_3 = uieditfield(app.GridLayout7, 'numeric');
            app.EditField_3.ValueDisplayFormat = '%.0f';
            app.EditField_3.Value = 20;
            app.EditField_4 = uieditfield(app.GridLayout7, 'numeric');
            app.EditField_4.ValueDisplayFormat = '%.0f';
            app.EditField_4.Value = 50;
            app.EditField_5 = uieditfield(app.GridLayout7, 'numeric');
            app.EditField_5.ValueDisplayFormat = '%.0f';
            app.EditField_5.Value = 101325;
            app.TemperatureLabel = uilabel(app.GridLayout7);
            app.TemperatureLabel.Text = 'Temperature';
            app.CALIBRATIONTab = uitab(app.TabGroup);
            app.CALIBRATIONTab.Title = 'CALIBRATION';
            app.GridLayout13 = uigridlayout(app.CALIBRATIONTab);
            app.FFTPanel = uipanel(app.GridLayout13);
            app.FFTPanel.Title = 'FFT';
            app.GridLayout12_2 = uigridlayout(app.FFTPanel);
            app.UIAxes_LiveFFT = uiaxes(app.GridLayout12_2);
            title(app.UIAxes_LiveFFT, 'FFT')
            xlabel(app.UIAxes_LiveFFT, 'Frequency [Hz]')
            ylabel(app.UIAxes_LiveFFT, 'SPL [dB]')
            zlabel(app.UIAxes_LiveFFT, 'Z')
            app.StartButton = uibutton(app.GridLayout13, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.GridLayout13, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.MEASUREMENTTab = uitab(app.TabGroup);
            app.MEASUREMENTTab.Title = 'MEASUREMENT';
            app.DATAPROCESSINGTab = uitab(app.TabGroup);
            app.DATAPROCESSINGTab.Title = 'DATA PROCESSING';
            app.GridLayout2 = uigridlayout(app.DATAPROCESSINGTab);
            app.UIAxes = uiaxes(app.GridLayout2);
            title(app.UIAxes, 'Default Graph 1')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.GridLayout2);
            title(app.UIAxes2, 'Default Graph 2')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.GridLayout2);
            title(app.UIAxes3, 'Default Graph 3')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes4 = uiaxes(app.GridLayout2);
            title(app.UIAxes4, 'Default Graph 4')
            zlabel(app.UIAxes4, 'Z')
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.RightPanel);
            app.Panel = uipanel(app.GridLayout3);
            app.GridLayout4 = uigridlayout(app.Panel);
            app.DefaultCheckBox1 = uicheckbox(app.GridLayout4);
            app.DefaultCheckBox1.ValueChangedFcn = createCallbackFcn(app, @DefaultCheckBox1ValueChanged, true);
            app.DefaultCheckBox1.Enable = 'off';
            app.DefaultCheckBox1.Text = 'Default Check Box 1';
            app.DefaultCheckBox1.Value = true;
            app.DefaultCheckBox2 = uicheckbox(app.GridLayout4);
            app.DefaultCheckBox2.ValueChangedFcn = createCallbackFcn(app, @DefaultCheckBox2ValueChanged, true);
            app.DefaultCheckBox2.Enable = 'off';
            app.DefaultCheckBox2.Text = 'Default Check Box 2';
            app.DefaultCheckBox2.Value = true;
            app.DefaultCheckBox3 = uicheckbox(app.GridLayout4);
            app.DefaultCheckBox3.ValueChangedFcn = createCallbackFcn(app, @DefaultCheckBox3ValueChanged, true);
            app.DefaultCheckBox3.Enable = 'off';
            app.DefaultCheckBox3.Text = 'Default Check Box 3';
            app.DefaultCheckBox3.Value = true;
            app.DefaultCheckBox4 = uicheckbox(app.GridLayout4);
            app.DefaultCheckBox4.ValueChangedFcn = createCallbackFcn(app, @DefaultCheckBox4ValueChanged, true);
            app.DefaultCheckBox4.Enable = 'off';
            app.DefaultCheckBox4.Text = 'Default Check Box 4';
            app.DefaultCheckBox4.Value = true;
            app.Panel_7 = uipanel(app.GridLayout4);
            app.Panel_7.Visible = 'off';
            app.GridLayout10 = uigridlayout(app.Panel_7);
            app.Button = uibutton(app.GridLayout10, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '1';
            app.Button_3 = uibutton(app.GridLayout10, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '3';
            app.Button_2 = uibutton(app.GridLayout10, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '2';
            app.Panel_2 = uipanel(app.GridLayout3);
            app.GridLayout5 = uigridlayout(app.Panel_2);
            app.ListBox = uilistbox(app.GridLayout5);
            app.ListBox.Items = {};
            app.ListBox.Multiselect = 'on';
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @ListBoxValueChanged, true);
            app.ListBox.ClickedFcn = createCallbackFcn(app, @ListBoxClicked, true);
            app.ListBox.Value = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AAA_Iter5

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end