classdef AAA_Iter5_Load < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        GridLayout            matlab.ui.container.GridLayout
        Panel                 matlab.ui.container.Panel
        GridLayout2           matlab.ui.container.GridLayout
        NameisalreadyinusepickagainLabel  matlab.ui.control.Label
        EditField             matlab.ui.control.EditField
        BrowseDataButton      matlab.ui.control.Button
        MeasurementNameLabel  matlab.ui.control.Label
    end

    
    properties (Access = public)
        AAA_Iter5_app; % Description
        name_in_use = false;
    end
    
    methods (Access = private)
        
        function change_check_boxes_visibility(app, bool)

            app.AAA_Iter5_app.Panel_7.Visible = bool; 
            app.AAA_Iter5_app.GridLayout10.Visible = bool; 
            app.AAA_Iter5_app.DefaultCheckBox1.Enable = bool; 
            app.AAA_Iter5_app.DefaultCheckBox2.Enable = bool; 
            app.AAA_Iter5_app.DefaultCheckBox3.Enable = bool; 
            app.AAA_Iter5_app.DefaultCheckBox4.Enable = bool; 
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller)
            app.AAA_Iter5_app = caller; 

            % Get the screen size
            screensize = get(groot, 'ScreenSize');

            % Calculate the new position for the figure
            appwidth = 500;
            appheight = 250;
            appx = (screensize(3) - appwidth) / 2;
            appy = (screensize(4) - appheight) / 2;

            % Set the new position for the figure
            set(app.UIFigure, 'Position', [appx, appy, appwidth, appheight]);
        end

        % Button pushed function: BrowseDataButton
        function BrowseDataButtonPushed(app, event)
            

            for i = 1:10 
                app.AAA_Iter5_app.previous_list_box_data(i) = "0";
            end
            
            data_value = app.AAA_Iter5_app.number_data_loaded_in;
            app.AAA_Iter5_app.stored_tab_data(data_value).LOAD_MAT_FILE;

            app.AAA_Iter5_app.stored_tab_data(data_value).selected_title = app.EditField.Value;
            title_data = app.EditField.Value;
            app.AAA_Iter5_app.filename_data_arr(data_value) = cellstr(title_data);
            app.AAA_Iter5_app.ListBox.Items = app.AAA_Iter5_app.filename_data_arr;
            app.AAA_Iter5_app.list_box_index = app.AAA_Iter5_app.list_box_index +1; 


            progress_bar = uiprogressdlg(app.UIFigure, 'Title', 'B&W Active Absorber App', 'Message', 'Loading Data', 'Indeterminate','on'); 

            iteration_value = app.AAA_Iter5_app.number_data_loaded_in;
            list_box_value = num2str(cell2mat(app.AAA_Iter5_app.filename_data_arr(iteration_value)));
           
            app.AAA_Iter5_app.ListBox.Value = list_box_value;
            app.AAA_Iter5_app.previous_list_box_data(iteration_value) = list_box_value;
            app.AAA_Iter5_app.plot_multiple_data();
            
            app.AAA_Iter5_app.number_data_loaded_in = app.AAA_Iter5_app.number_data_loaded_in + 1; 

            app.AAA_Iter5_app.TabGroup.SelectedTab =  app.AAA_Iter5_app.DATAPROCESSINGTab;
            
            pause(1); 
            close(progress_bar); 
            change_check_boxes_visibility(app, true);
            delete(app); 
        end

        % Value changing function: EditField
        function EditFieldValueChanging(app, event)
            changingValue = event.Value;

%             sample_size = 100; 
            for i = 1:max(size(app.AAA_Iter5_app.filename_data_arr)) 

                string_value = num2str(cell2mat(app.AAA_Iter5_app.filename_data_arr(i)));
                strcmp_value = strcmp(changingValue, string_value); 

                string_value_fullstop = string_value + "."; 
                strcmp_value_extended = strcmp(changingValue, string_value_fullstop); 

                string_value_array = strings(1,100); 
                strcmp_value_array = zeros(1,100);
                

                for j = 1:100 

                    if j == 1
                        string_value_array(1) = string_value; 
                        strcmp_value_array(1) = strcmp(changingValue, string_value_array(1)); 
                    else
                        string_value_array(j) = string_value_array(j-1) + " "; 
                        strcmp_value_array(j) = strcmp(changingValue, string_value_array(j));
                    end
                end


                for k = 1:100 

                    if strcmp_value_array(k) == 1
                        
                        app.name_in_use = true; 
                        break;
                    end


                end

                if strcmp_value == 1 || strcmp_value_extended == 1 || app.name_in_use == true

                    app.NameisalreadyinusepickagainLabel.Visible = true; 
                    app.BrowseDataButton.Visible = false; 
                    app.name_in_use = false;
                    break; 
                elseif strcmp_value == 0 
                    app.NameisalreadyinusepickagainLabel.Visible = false; 
                    app.BrowseDataButton.Visible = true; 
                end

                
            end


            %             changingValue = event.Value;
% 
%             space_size = 20; 

%             max_size = max(size(app.AAA_Iter5_app.filename_data_arr));
% 
%             string_value = num2str(cell2mat(app.AAA_Iter5_app.filename_data_arr(max_size)));
% 
%             string_value_arr(max_size) = string_value; 
% 
%             disp(string_value_arr);


%             for i = 1:max(size(app.AAA_Iter5_app.filename_data_arr)) 
% 
%                 
%                 string_value_fullstop = string_value + ".";
%                 strcmp_value_fullstop = strcmp(changingValue, string_value_fullstop); 
% 
%                 string_value_arr = strings(1,space_size);
%                 string_value_arr(i) = string_value; 
% 
%                 
% 
%                 
%             end  


            %set up al the values in the string_value_arr array, this
            %includes all the full stops and spaces. Track how big this
            %array is, then do a strcmp with a for loop to do the logic of
            %the if and else statement below 


% 
%             disp(string_value_arr); 
% 
% %                 for j = 1:space_size
% % 
% %                     if j == 1
% %                         string_value_arr(j) = string_value;
% %                     else
% %                         string_value_arr(j) = string_value_arr(j-1) + " ";
% %                     end
% %                 end    
% 
%                 strcmp_value = strcmp(changingValue, string_value_arr(i)); 
% 
%                 if strcmp_value == 1 || strcmp_value_fullstop == 1
%                     app.NameisalreadyinusepickagainLabel.Visible = true; 
%                     app.BrowseDataButton.Visible = false;  
%                     break; 
%                 else
%                     app.NameisalreadyinusepickagainLabel.Visible = false; 
%                     app.BrowseDataButton.Visible = true; 
%                 end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.Panel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.Panel);
            app.MeasurementNameLabel = uilabel(app.GridLayout2);
            app.MeasurementNameLabel.Text = 'Measurement Name:';
            app.BrowseDataButton = uibutton(app.GridLayout2, 'push');
            app.BrowseDataButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseDataButtonPushed, true);
            app.BrowseDataButton.Text = 'Browse Data';
            app.EditField = uieditfield(app.GridLayout2, 'text');
            app.EditField.ValueChangingFcn = createCallbackFcn(app, @EditFieldValueChanging, true);
            app.NameisalreadyinusepickagainLabel = uilabel(app.GridLayout2);
            app.NameisalreadyinusepickagainLabel.Visible = 'off';
            app.NameisalreadyinusepickagainLabel.Text = 'Name is already in use - pick again';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AAA_Iter5_Load(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end